classdef classifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CLASSIFIERGUILabel              matlab.ui.control.Label
        SelectIPCameraURLDropDownLabel  matlab.ui.control.Label
        SelectIPCameraURLDropDown       matlab.ui.control.DropDown
        ActivateCameraButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        STOPCLASSIFICATIONButton        matlab.ui.control.StateButton
        Label_2                         matlab.ui.control.Label
        TypeURLEditFieldLabel           matlab.ui.control.Label
        TypeURLEditField                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        cam;% Description
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            app.cam=ipcam('http://192.168.43.1:8080/video');
            disp(app.cam)
            title(app.UIAxes, " ")
            xlabel(app.UIAxes, "IMAGE PREVIEW")
            ylabel(app.UIAxes, " ")
        end

        % Value changed function: SelectIPCameraURLDropDown
        function SelectIPCameraURLDropDownValueChanged(app, event)
           global urlSelect;
           
           urlSelect= app.SelectIPCameraURLDropDown.Value;
           
        end

        % Button pushed function: ActivateCameraButton
        function ActivateCameraButtonPushed(app, event)
            global urlSelect;
            global url;
            if(urlSelect=='1')
                url = 'http://192.168.43.1:8080/video';
               app.cam =ipcam(url);
           end
           if(urlSelect=='2')
               url = 'http://100.75.112.215:8080/video';
               app.cam =ipcam(url);
           end
           if(urlSelect=='3')
               url = app.TypeURLEditField.Value;
               app.cam =ipcam(url);
           end
           
%  To access data that is in base workspace in your gui functions,
%  use 
%  v = evalin('base','name of variable u want to access');
%  the variable's data is now stored in v.
%  To save data of gui to work space use
%  assignin('base','name of variable u want to save','value of variable');

           
           global stopClassify;
           
           stopClassify = 0;
           for i=1:1000
            if(~stopClassify)        
                img = snapshot(app.cam);
%                 im = image(app.UIAxes,zeros(size(img)));
                axis(app.UIAxes,'image')
                img1 = imresize(img,[224,224]);%RSETnet
                imshow(img,'Parent',app.UIAxes)
                nnet = evalin('base','RSETnet');
                [label,score] = classify(nnet,img1);
                title(app.UIAxes,string(label)+","+num2str(max(score),2))
                app.Label_2.Text=" ";
                
                drawnow()


             end
           end
%             preview(app.cam,im)
        end

        % Value changed function: STOPCLASSIFICATIONButton
        function STOPCLASSIFICATIONButtonValueChanged(app, event)
            global stopClassify
            stopClassify = app.STOPCLASSIFICATIONButton.Value;
            
        end

        % Value changed function: TypeURLEditField
        function TypeURLEditFieldValueChanged(app, event)
            value = app.TypeURLEditField.Value;
            
             global urlSelect;           
             urlSelect=3;
             global url;
             url = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CLASSIFIERGUILabel = uilabel(app.UIFigure);
            app.CLASSIFIERGUILabel.Text = 'CLASSIFIER GUI';
            app.SelectIPCameraURLDropDownLabel = uilabel(app.UIFigure);
            app.SelectIPCameraURLDropDownLabel.Text = 'Select IP Camera URL';
            app.SelectIPCameraURLDropDown = uidropdown(app.UIFigure);
            app.SelectIPCameraURLDropDown.Items = {'http://192.168.43.1:8080/video', 'http://100.75.112.215:8080/video'};
            app.SelectIPCameraURLDropDown.ItemsData = {'1', '2'};
            app.SelectIPCameraURLDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectIPCameraURLDropDownValueChanged, true);
            app.SelectIPCameraURLDropDown.Value = '1';
            app.ActivateCameraButton = uibutton(app.UIFigure, 'push');
            app.ActivateCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateCameraButtonPushed, true);
            app.ActivateCameraButton.Text = 'Activate Camera';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.STOPCLASSIFICATIONButton = uibutton(app.UIFigure, 'state');
            app.STOPCLASSIFICATIONButton.ValueChangedFcn = createCallbackFcn(app, @STOPCLASSIFICATIONButtonValueChanged, true);
            app.STOPCLASSIFICATIONButton.Text = 'STOP CLASSIFICATION';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TypeURLEditFieldLabel = uilabel(app.UIFigure);
            app.TypeURLEditFieldLabel.Text = 'Type URL';
            app.TypeURLEditField = uieditfield(app.UIFigure, 'text');
            app.TypeURLEditField.ValueChangedFcn = createCallbackFcn(app, @TypeURLEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = classifierGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end